classdef UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MissileSimulationUIFigure       matlab.ui.Figure
        AzimuthAngleEditField           matlab.ui.control.NumericEditField
        AzimuthAngleEditFieldLabel      matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        TargetStatusLabel               matlab.ui.control.Label
        TimeStepEditField               matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel          matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        SimulateButton                  matlab.ui.control.Button
        InitialMassEditField            matlab.ui.control.NumericEditField
        InitialMassEditFieldLabel       matlab.ui.control.Label
        TargetZEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel_2               matlab.ui.control.Label
        TargetYEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel_2               matlab.ui.control.Label
        TargetXEditField                matlab.ui.control.NumericEditField
        XEditFieldLabel_2               matlab.ui.control.Label
        TargetPositionLabel             matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        DragCoefficientEditField        matlab.ui.control.NumericEditField
        DragCoefficientEditFieldLabel   matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        ReferenceAreaEditField          matlab.ui.control.NumericEditField
        ReferenceAreaEditFieldLabel     matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        FuelFlowRateEditField           matlab.ui.control.NumericEditField
        FuelFlowRateEditFieldLabel      matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        BurnTimeEditField               matlab.ui.control.NumericEditField
        BurnTimeEditFieldLabel          matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        AvgThrustEditField              matlab.ui.control.NumericEditField
        AverageThrustEditFieldLabel     matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        DryMassEditField                matlab.ui.control.NumericEditField
        DryMassEditFieldLabel           matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ElevationAngleEditField         matlab.ui.control.NumericEditField
        ElevationAngleEditFieldLabel    matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        InitialVelocityEditField        matlab.ui.control.NumericEditField
        InitialVelocityEditFieldLabel   matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        InitialZEditField               matlab.ui.control.NumericEditField
        z0EditFieldLabel                matlab.ui.control.Label
        InitialYEditField               matlab.ui.control.NumericEditField
        y0EditFieldLabel                matlab.ui.control.Label
        InitialXEditField               matlab.ui.control.NumericEditField
        x0EditFieldLabel                matlab.ui.control.Label
        InitialPositionLabel            matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        InputPanelLabel                 matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        FlightRouteTab                  matlab.ui.container.Tab
        UIAxes3D                        matlab.ui.control.UIAxes
        SpeedAccelerationDragGraphsTab  matlab.ui.container.Tab
        DragForceAxes                   matlab.ui.control.UIAxes
        AccelerationAxes                matlab.ui.control.UIAxes
        VelocityAxes                    matlab.ui.control.UIAxes
        DataTables                      matlab.ui.container.Tab
        DataTable                       matlab.ui.control.Table
        SummaryTab                      matlab.ui.container.Tab
        SummaryOutputLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        SimTime;
        SimPosition;
        SimVelocity;
        SimAcceleration;
        SimDragForce;
        
        % Target
        TargetX;
        TargetY;
        TargetZ;
        AzimuthAngle; 
    end
    
    methods (Access = private)
        
        function updatePlots(app)
        % Flight Route (3D)
        cla(app.UIAxes3D);
        plot3(app.UIAxes3D, app.SimPosition(:,1), app.SimPosition(:,2), app.SimPosition(:,3), 'b-', 'LineWidth', 1.5);
        hold(app.UIAxes3D, 'on');
        plot3(app.UIAxes3D, app.InitialXEditField.Value, app.InitialYEditField.Value, app.InitialZEditField.Value, 'go', 'MarkerFaceColor', 'g', 'MarkerSize', 8); % Starting point
        plot3(app.UIAxes3D, app.TargetXEditField.Value, app.TargetYEditField.Value, app.TargetZEditField.Value, 'rx', 'MarkerSize', 10, 'LineWidth', 2); % Target point
        grid(app.UIAxes3D, 'on');
        xlabel(app.UIAxes3D, 'X Location (m)');
        ylabel(app.UIAxes3D, 'Y Location (m)');
        zlabel(app.UIAxes3D, 'Z Location (m)');
        title(app.UIAxes3D, 'Missile Flight Path (3D)');
        legend(app.UIAxes3D, 'Missile Trajectory', 'Beginning', 'Target', 'Location', 'best');
        view(app.UIAxes3D, 3);
    
        % Speed Graph
        cla(app.VelocityAxes);
        plot(app.VelocityAxes, app.SimTime, vecnorm(app.SimVelocity, 2, 2), 'b-', 'DisplayName', 'Total Velocity');
        hold(app.VelocityAxes, 'on');
        plot(app.VelocityAxes, app.SimTime, app.SimVelocity(:,1), 'r--', 'DisplayName', 'Vx');
        plot(app.VelocityAxes, app.SimTime, app.SimVelocity(:,2), 'g:', 'DisplayName', 'Vy');
        plot(app.VelocityAxes, app.SimTime, app.SimVelocity(:,3), 'm-.', 'DisplayName', 'Vz');
        hold(app.VelocityAxes, 'off');
        grid(app.VelocityAxes, 'on');
        xlabel(app.VelocityAxes, 'Time (s)');
        ylabel(app.VelocityAxes, 'Velocity (m/s)');
        title(app.VelocityAxes, 'Velocity Components with Respect to Time');
        legend(app.VelocityAxes, 'Location', 'best');
    
        % Acceleration Graph
        cla(app.AccelerationAxes);
        plot(app.AccelerationAxes, app.SimTime, vecnorm(app.SimAcceleration, 2, 2), 'b-', 'DisplayName', 'Total Acceleration');
        hold(app.AccelerationAxes, 'on');
        plot(app.AccelerationAxes, app.SimTime, app.SimAcceleration(:,1), 'r--', 'DisplayName', 'Ax');
        plot(app.AccelerationAxes, app.SimTime, app.SimAcceleration(:,2), 'g:', 'DisplayName', 'Ay');
        plot(app.AccelerationAxes, app.SimTime, app.SimAcceleration(:,3), 'm-.', 'DisplayName', 'Az');
        hold(app.AccelerationAxes, 'off');
        grid(app.AccelerationAxes, 'on');
        xlabel(app.AccelerationAxes, 'Time (s)');
        ylabel(app.AccelerationAxes, 'Acceleration (m/s^2)');
        title(app.AccelerationAxes, 'Acceleration Components with Respect to Time');
        legend(app.AccelerationAxes, 'Location', 'best');
    
        % Drag Force Graph
        cla(app.DragForceAxes);
        plot(app.DragForceAxes, app.SimTime, app.SimDragForce, 'k-', 'LineWidth', 1.5, Color=[0,0.7,0.9]);
        grid(app.DragForceAxes, 'on');
        xlabel(app.DragForceAxes, 'Time (s)');
        ylabel(app.DragForceAxes, 'Drag Force (N)');
        title(app.DragForceAxes, 'Drag Force with Respect to Time');
    end
    
    function updateTables(app)
        displayData = [app.SimTime, app.SimPosition, app.SimVelocity, app.SimAcceleration, app.SimDragForce];

        columnNames = {'Time (s)', 'X (m)', 'Y (m)', 'Z (m)', ...
                       'Vx (m/s)', 'Vy (m/s)', 'Vz (m/s)', ...
                       'Ax (m/s^2)', 'Ay (m/s^2)', 'Az (m/s^2)', ...
                       'Drag Force (N)'};
        
        app.DataTable.Data = displayData;
        app.DataTable.ColumnName = columnNames;
    end
    
    function checkTargetHit(app, hitTarget, finalDistanceToTarget)
        if hitTarget
            app.TargetStatusLabel.Text = sprintf('TARGET HIT! (Final Distance: %.2f m)', finalDistanceToTarget);
            app.TargetStatusLabel.FontColor = [0 0.8 0]; % Green
        else
            app.TargetStatusLabel.Text = sprintf('Target Missed. (Last Distance: %.2f m)', finalDistanceToTarget);
            app.TargetStatusLabel.FontColor = [0.8 0 0]; % Red
        end
    end

    function updateSummary(app, params, hitTarget, finalDistanceToTarget)
    % Retrieve initial parameters from app for display
    initialX = app.InitialXEditField.Value;
    initialY = app.InitialYEditField.Value;
    initialZ = app.InitialZEditField.Value;
    initialVelocity = app.InitialVelocityEditField.Value;
    elevationAngle = app.ElevationAngleEditField.Value;
    azimuthAngle = app.AzimuthAngleEditField.Value;

    targetX = app.TargetXEditField.Value;
    targetY = app.TargetYEditField.Value;
    targetZ = app.TargetZEditField.Value;

    flightDuration = app.SimTime(end); % Total flight duration
    maxAltitude = max(app.SimPosition(:,3)); % Maximum Z position reached

    % Build the summary string
    summaryText = {
        'SIMULATION SUMMARY';
        '--------------------';
        sprintf('• Initial Position: (%.1f, %.1f, %.1f) m', initialX, initialY, initialZ);
        sprintf('• Initial Velocity: %.1f m/s', initialVelocity);
        sprintf('• Elevation Angle: %.1f degrees', elevationAngle);
        sprintf('• Azimuth Angle: %.1f degrees', azimuthAngle);
        sprintf('• Target Position: (%.1f, %.1f, %.1f) m', targetX, targetY, targetZ);
        sprintf('• Flight Duration: %.2f seconds', flightDuration);
        sprintf('• Max Altitude Reached: %.2f m', maxAltitude);
        ''; % Empty line for spacing
    };

    % Add target hit status
    if hitTarget
        summaryText{end+1} = sprintf('• Target Status: HIT! (Final Distance: %.2f m)', finalDistanceToTarget);
        app.SummaryOutputLabel.FontColor = [0 0.6 0]; % Dark Green
    else
        summaryText{end+1} = sprintf('• Target Status: MISSED! (Final Distance: %.2f m)', finalDistanceToTarget);
        app.SummaryOutputLabel.FontColor = [0.8 0 0]; % Dark Red
    end

    % Set the label's text
    app.SummaryOutputLabel.Text = strjoin(summaryText, '\n');
    end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set default values
            app.InitialXEditField.Value = 0;
            app.InitialYEditField.Value = 0;
            app.InitialZEditField.Value = 0;
            app.InitialVelocityEditField.Value = 200; % m/s
            app.ElevationAngleEditField.Value = 45; % degree
            app.AzimuthAngleEditField.Value = 0; % degree
            app.InitialMassEditField.Value = 100; % kg
            app.DryMassEditField.Value = 50; % kg
            app.AvgThrustEditField.Value = 2000; % N
            app.BurnTimeEditField.Value = 10; % s
            app.FuelFlowRateEditField.Value = 5; % kg/s
            app.ReferenceAreaEditField.Value = 0.1; % m^2
            app.DragCoefficientEditField.Value = 0.5; % Cd
            app.TargetXEditField.Value = 1000; % m
            app.TargetYEditField.Value = 0; % m
            app.TargetZEditField.Value = 0; % m
            app.TimeStepEditField.Value = 0.01; % s
            
            % Clear chart axes and tables
            cla(app.UIAxes3D);
            cla(app.VelocityAxes);
            cla(app.AccelerationAxes);
            cla(app.DragForceAxes);
            app.DataTable.Data = [];
            app.TargetStatusLabel.Text = 'Awaiting Simulation...';
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % Get input parameters from UI
            params = struct();
            params.initialPosition = [app.InitialXEditField.Value, ...
                                      app.InitialYEditField.Value, ...
                                      app.InitialZEditField.Value];
            params.initialVelocityMagnitude = app.InitialVelocityEditField.Value;
            params.elevationAngleDeg  = app.ElevationAngleEditField.Value;
            params.azimuthAngleDeg = app.AzimuthAngleEditField.Value;
            params.initialMass = app.InitialMassEditField.Value;
            params.dryMass = app.DryMassEditField.Value;
            params.avgThrust = app.AvgThrustEditField.Value;
            params.burnTime = app.BurnTimeEditField.Value;
            params.fuelFlowRate = app.FuelFlowRateEditField.Value;
            params.referenceArea = app.ReferenceAreaEditField.Value;
            params.dragCoefficient = app.DragCoefficientEditField.Value;
            params.targetPosition = [app.TargetXEditField.Value, ...
                                     app.TargetYEditField.Value, ...
                                     app.TargetZEditField.Value];
            params.dt = app.TimeStepEditField.Value;
            
            % Parameter validation
            if params.initialMass <= params.dryMass
                uiconfirm(app.UIFigure, 'Initial mass must be greater than dry mass.', 'Error', 'Icon','error');
                return;
            end
            if params.burnTime > 0 && params.fuelFlowRate == 0
                 uiconfirm(app.UIFigure, 'If there is a burn time, the fuel flow rate cannot be zero.', 'Error', 'Icon','error');
                 return;
            end
            if params.burnTime == 0 && params.avgThrust > 0
                uiconfirm(app.UIFigure, 'If the burn time is zero, the thrust force must be zero..', 'Error', 'Icon','error');
                return;
            end
    
            app.TargetStatusLabel.Text = 'Simulation is Running...';
            app.SummaryOutputLabel.Text = 'Simulation Running...';
            drawnow;
            
            try
                [app.SimTime, app.SimPosition, app.SimVelocity, app.SimAcceleration, app.SimDragForce, hitTarget, finalDistanceToTarget] = simulateMissile(params);
                
                % Visualize the results
                app.updatePlots();
                app.updateTables();
                app.checkTargetHit(hitTarget, finalDistanceToTarget);
                app.updateSummary(params, hitTarget, finalDistanceToTarget);
                
            catch ME
                uialert(app.MissileSimulationUIFigure, ['Simulation error: ' ME.message], 'Error', 'Icon','error');
                app.TargetStatusLabel.Text = 'Simulation error';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset all input fields to default values
            app.InitialXEditField.Value = 0;
            app.InitialYEditField.Value = 0;
            app.InitialZEditField.Value = 0;
            app.InitialVelocityEditField.Value = 200;
            app.ElevationAngleEditField.Value = 45;
            app.AzimuthAngleEditField.Value = 0;
            app.InitialMassEditField.Value = 100;
            app.DryMassEditField.Value = 50;
            app.AvgThrustEditField.Value = 2000;
            app.BurnTimeEditField.Value = 10;
            app.FuelFlowRateEditField.Value = 5;
            app.ReferenceAreaEditField.Value = 0.1;
            app.DragCoefficientEditField.Value = 0.5;
            app.TargetXEditField.Value = 1000;
            app.TargetYEditField.Value = 0;
            app.TargetZEditField.Value = 0;
            app.TimeStepEditField.Value = 0.01;
            
            % Clear chart axes
            cla(app.UIAxes3D);
            cla(app.VelocityAxes);
            cla(app.AccelerationAxes);
            cla(app.DragForceAxes);
            
            % Clear table
            app.DataTable.Data = [];
            app.DataTable.ColumnName = {};
            
            % Reset target status label
            app.TargetStatusLabel.Text = 'Awaiting Simulation...';
            app.SummaryOutputLabel.Text = 'Simulation Pending...';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MissileSimulationUIFigure = uifigure('Visible', 'off');
            app.MissileSimulationUIFigure.Name = 'MissileSimulation';
            app.TabGroup = uitabgroup(app.MissileSimulationUIFigure);
            app.FlightRouteTab = uitab(app.TabGroup);
            app.FlightRouteTab.Title = 'Flight Route';
            app.UIAxes3D = uiaxes(app.FlightRouteTab);
            title(app.UIAxes3D, 'Title')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            app.SpeedAccelerationDragGraphsTab = uitab(app.TabGroup);
            app.SpeedAccelerationDragGraphsTab.Title = 'Speed, Acceleration, Drag Graphs';
            app.VelocityAxes = uiaxes(app.SpeedAccelerationDragGraphsTab);
            title(app.VelocityAxes, 'Speed Graph')
            xlabel(app.VelocityAxes, 'X')
            ylabel(app.VelocityAxes, 'Y')
            zlabel(app.VelocityAxes, 'Z')
            app.AccelerationAxes = uiaxes(app.SpeedAccelerationDragGraphsTab);
            title(app.AccelerationAxes, 'Acceleration Graph')
            xlabel(app.AccelerationAxes, 'X')
            ylabel(app.AccelerationAxes, 'Y')
            zlabel(app.AccelerationAxes, 'Z')
            app.DragForceAxes = uiaxes(app.SpeedAccelerationDragGraphsTab);
            title(app.DragForceAxes, 'Drag Graph')
            xlabel(app.DragForceAxes, 'X')
            ylabel(app.DragForceAxes, 'Y')
            zlabel(app.DragForceAxes, 'Z')
            app.DataTables = uitab(app.TabGroup);
            app.DataTables.Title = 'Data Tables';
            app.DataTable = uitable(app.DataTables);
            app.DataTable.ColumnName = {'Time'; 'PositionX'; 'PositionY'; 'PositionZ'; 'VelocityX'; 'VelocityY'; 'VelocityZ'; 'AccelerationX'; 'AccelerationY'; 'AccelerationZ'; 'Drag Force'};
            app.DataTable.RowName = {};
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.SummaryOutputLabel = uilabel(app.SummaryTab);
            app.SummaryOutputLabel.Text = 'Awaiting Simulation...';
            app.InputPanelLabel = uilabel(app.MissileSimulationUIFigure);
            app.InputPanelLabel.Text = 'Input Panel';
            app.Label = uilabel(app.MissileSimulationUIFigure);
            app.Label.Text = '';
            app.InitialPositionLabel = uilabel(app.MissileSimulationUIFigure);
            app.InitialPositionLabel.Text = 'Initial Position';
            app.x0EditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.InitialXEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.y0EditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.y0EditFieldLabel.Text = 'y0';
            app.InitialYEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.z0EditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.z0EditFieldLabel.Text = 'z0';
            app.InitialZEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.Label_2 = uilabel(app.MissileSimulationUIFigure);
            app.Label_2.Text = '';
            app.InitialVelocityEditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.InitialVelocityEditFieldLabel.Text = 'Initial Velocity';
            app.InitialVelocityEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.Label_3 = uilabel(app.MissileSimulationUIFigure);
            app.Label_3.Text = '';
            app.ElevationAngleEditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.ElevationAngleEditFieldLabel.Text = 'Elevation Angle';
            app.ElevationAngleEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.Label_4 = uilabel(app.MissileSimulationUIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.MissileSimulationUIFigure);
            app.Label_5.Text = '';
            app.DryMassEditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.DryMassEditFieldLabel.Text = 'Dry Mass';
            app.DryMassEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.Label_6 = uilabel(app.MissileSimulationUIFigure);
            app.Label_6.Text = '';
            app.AverageThrustEditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.AverageThrustEditFieldLabel.Text = 'Average Thrust';
            app.AvgThrustEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.Label_7 = uilabel(app.MissileSimulationUIFigure);
            app.Label_7.Text = '';
            app.BurnTimeEditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.BurnTimeEditFieldLabel.Text = 'Burn Time';
            app.BurnTimeEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.Label_8 = uilabel(app.MissileSimulationUIFigure);
            app.Label_8.Text = '';
            app.FuelFlowRateEditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.FuelFlowRateEditFieldLabel.Text = 'Fuel Flow Rate';
            app.FuelFlowRateEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.Label_9 = uilabel(app.MissileSimulationUIFigure);
            app.Label_9.Text = '';
            app.ReferenceAreaEditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.ReferenceAreaEditFieldLabel.Text = 'Reference Area';
            app.ReferenceAreaEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.Label_10 = uilabel(app.MissileSimulationUIFigure);
            app.Label_10.Text = '';
            app.DragCoefficientEditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.DragCoefficientEditFieldLabel.Text = 'Drag Coefficient';
            app.DragCoefficientEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.Label_11 = uilabel(app.MissileSimulationUIFigure);
            app.Label_11.Text = '';
            app.TargetPositionLabel = uilabel(app.MissileSimulationUIFigure);
            app.TargetPositionLabel.Text = 'Target Position';
            app.XEditFieldLabel_2 = uilabel(app.MissileSimulationUIFigure);
            app.XEditFieldLabel_2.Text = 'xT';
            app.TargetXEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.YEditFieldLabel_2 = uilabel(app.MissileSimulationUIFigure);
            app.YEditFieldLabel_2.Text = 'yT';
            app.TargetYEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.ZEditFieldLabel_2 = uilabel(app.MissileSimulationUIFigure);
            app.ZEditFieldLabel_2.Text = 'zT';
            app.TargetZEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.InitialMassEditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.InitialMassEditFieldLabel.Text = 'Initial Mass';
            app.InitialMassEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.SimulateButton = uibutton(app.MissileSimulationUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ResetButton = uibutton(app.MissileSimulationUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label_12 = uilabel(app.MissileSimulationUIFigure);
            app.Label_12.Text = '';
            app.TimeStepEditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            app.TargetStatusLabel = uilabel(app.MissileSimulationUIFigure);
            app.Label_13 = uilabel(app.MissileSimulationUIFigure);
            app.Label_13.Text = '';
            app.AzimuthAngleEditFieldLabel = uilabel(app.MissileSimulationUIFigure);
            app.AzimuthAngleEditFieldLabel.Text = 'Azimuth Angle';
            app.AzimuthAngleEditField = uieditfield(app.MissileSimulationUIFigure, 'numeric');
            % Show the figure after all components are created
            app.MissileSimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MissileSimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MissileSimulationUIFigure)
        end
    end
end